classdef LoginPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        MailIDEditField       matlab.ui.control.EditField
        MailIDEditFieldLabel  matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        LoginButton           matlab.ui.control.Button
        SignUpButton          matlab.ui.control.Button
        LoginPageLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignUpButton
        function SignUpNextPage(app, event)
            LoginPageSignUp
            app.UIFigure.Visible="off";
        end

        % Button pushed function: LoginButton
        function LoginClick(app, event)
           n=login(app.NameEditField.Value,app.MailIDEditField.Value);
           if n==0
               objj=InterviewerPage;
               objj.InterviewerName=app.NameEditField.Value;
               objj
               app.UIFigure.Visible="off";
           elseif n==1
               AdminPage;
               app.UIFigure.Visible="off";
           else
               app.Label.Text = "Please Sign Up";
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginPageLabel = uilabel(app.UIFigure);
            app.LoginPageLabel.Text = 'Login Page';
            app.SignUpButton = uibutton(app.UIFigure, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpNextPage, true);
            app.SignUpButton.Text = 'Sign Up';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginClick, true);
            app.LoginButton.Text = 'Login';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.MailIDEditFieldLabel = uilabel(app.UIFigure);
            app.MailIDEditFieldLabel.Text = 'Mail ID:';
            app.MailIDEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end