classdef LoginPageSignUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DomainDropDown           matlab.ui.control.DropDown
        DomainDropDownLabel      matlab.ui.control.Label
        SignUpButton             matlab.ui.control.Button
        DepartmentDropDown       matlab.ui.control.DropDown
        DepartmentDropDownLabel  matlab.ui.control.Label
        GetTheMailIDLabel        matlab.ui.control.Label
        GetTheNameusetheuniqueidfunctiontogetdetailsLabel  matlab.ui.control.Label
        MailIDLabel              matlab.ui.control.Label
        NameLabel                matlab.ui.control.Label
        SignUpLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignUpButton
        function SignUpNextPage(app, event)
            signup(app.NameLabel.Value,app.DepartmentDropDown.Value,app.DomainDropDown.Value,app.GetTheMailIDLabel.Value)
            app.UIFigure.Visible="off";
            LoginPage
        end

        % Value changed function: DepartmentDropDown
        function ChangeDomainaccDepartment(app, event)
            value = app.DepartmentDropDown.Value;
            if value == "Human Resource"
                app.DomainDropDown.Items={'NA'};
            elseif value== "Manager"
                app.DomainDropDown.Items={'NA'};
            else
                app.DomainDropDown.Items={'Computer Science','Electronics','Electrical','Embedded System','Control System','VLSI','Signal Processing&Communication'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignUpLabel = uilabel(app.UIFigure);
            app.SignUpLabel.Text = 'Sign Up';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.MailIDLabel = uilabel(app.UIFigure);
            app.MailIDLabel.Text = 'Mail ID';
            app.GetTheNameusetheuniqueidfunctiontogetdetailsLabel = uilabel(app.UIFigure);
            app.GetTheNameusetheuniqueidfunctiontogetdetailsLabel.Text = 'Get The Name use the unique id function to get details.';
            app.GetTheMailIDLabel = uilabel(app.UIFigure);
            app.GetTheMailIDLabel.Text = 'Get The Mail ID';
            app.DepartmentDropDownLabel = uilabel(app.UIFigure);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.UIFigure);
            app.DepartmentDropDown.Items = {'<Select>', 'Human Resource', 'Technical', 'Manager'};
            app.DepartmentDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDomainaccDepartment, true);
            app.DepartmentDropDown.Value = '<Select>';
            app.SignUpButton = uibutton(app.UIFigure, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpNextPage, true);
            app.SignUpButton.Text = 'Sign Up';
            app.DomainDropDownLabel = uilabel(app.UIFigure);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.UIFigure);
            app.DomainDropDown.Items = {'<Select>'};
            app.DomainDropDown.Value = '<Select>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginPageSignUp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end